classdef swsort_v0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        resetButton             matlab.ui.control.Button
        addpolygonButton        matlab.ui.control.Button
        clearselectedButton     matlab.ui.control.Button
        UndoButton              matlab.ui.control.Button
        PickOutButton           matlab.ui.control.Button
        SafeSortButton          matlab.ui.control.Button
        CombineUnitButton       matlab.ui.control.Button
        UnitDropDownLabel       matlab.ui.control.Label
        UnitDropDown            matlab.ui.control.DropDown
        UnitDropDown_2Label     matlab.ui.control.Label
        UnitDropDown_2          matlab.ui.control.DropDown
        SqueezeButton           matlab.ui.control.Button
        PushtotempButton        matlab.ui.control.Button
        p_okEditFieldLabel      matlab.ui.control.Label
        p_okEditField           matlab.ui.control.NumericEditField
        p_rejectEditFieldLabel  matlab.ui.control.Label
        p_rejectEditField       matlab.ui.control.NumericEditField
        SemiSafeSortButton      matlab.ui.control.Button
        OverwriteSwitchLabel    matlab.ui.control.Label
        OverwriteSwitch         matlab.ui.control.Switch
        PicktotempButton        matlab.ui.control.Button
        PickbackButton          matlab.ui.control.Button
        CleartempButton         matlab.ui.control.Button
        PCAtotempButton         matlab.ui.control.Button
        PC1DropDownLabel        matlab.ui.control.Label
        PC1DropDown             matlab.ui.control.DropDown
        PC2DropDownLabel        matlab.ui.control.Label
        PC2DropDown             matlab.ui.control.DropDown
        FilenameLabel           matlab.ui.control.Label
        NextfileButton          matlab.ui.control.Button
        PreviousfileButton      matlab.ui.control.Button
        LoadfolderButton        matlab.ui.control.Button
        LoadtimemarksButton     matlab.ui.control.Button
        labelEditFieldLabel     matlab.ui.control.Label
        labelEditField          matlab.ui.control.NumericEditField
        t_preEditFieldLabel     matlab.ui.control.Label
        t_preEditField          matlab.ui.control.NumericEditField
        t_afterEditFieldLabel   matlab.ui.control.Label
        t_afterEditField        matlab.ui.control.NumericEditField
        nPCDropDownLabel        matlab.ui.control.Label
        nPCDropDown             matlab.ui.control.DropDown
        ReducedRangeCheckBox    matlab.ui.control.CheckBox
        SortRatioButton         matlab.ui.control.Button
        rejectEditFieldLabel    matlab.ui.control.Label
        rejectEditField         matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        files
        ax
        hd % handle
        data
        feature
        setting
        user
        history
        temp
        tmmk
    end
    
    methods (Access = private)
        function initialize(app)
            app.initialize_params;
            app.initialize_axes;
            app.initialize_uis;
            app.reset;
        end
        function initialize_params(app)
            app.tmmk = [];
            app.setting.n_unit = 5; %  number 6 is noise
            app.setting.colors = {'k','r','b','c','m',...
                [0, 0.5, 0],[0.9290, 0.6940, 0.1250], ...
                [0.6350, 0.0780, 0.1840], [0.4940, 0.1840, 0.5560], ...
                [0.83 0.83 0.83]};
            app.setting.dotsize = 5;
            app.setting.autosave = 1;
        end
        function initialize_axes(app)
            offset = 0.2;
            app.ax.pca = axes(app.UIFigure, 'Position', [0.03, 0.5, 0.25, 0.45]);
            app.ax.temp = axes(app.UIFigure, 'Position', [0.03, 0.25, 0.15, 0.21]);
            app.ax.fr = axes(app.UIFigure, 'Position', [0.03, 0.03, 0.15, 0.21]);
            hold(app.ax.pca,"on");
            hold(app.ax.temp,"on");
            hold(app.ax.fr,"on");
            for ui = 1:(app.setting.n_unit + 1)
                app.hd.pca{ui} = plot(app.ax.pca, NaN, NaN, '.', 'MarkerSize', app.setting.dotsize, ...
                    'MarkerEdgeColor', app.setting.colors{ui});
                
                app.hd.fr{ui} = plot(app.ax.fr, NaN, NaN, '-', 'Color', app.setting.colors{ui});
                app.hd.temp{ui} = plot(app.ax.temp, NaN, NaN, '-', 'Color', app.setting.colors{ui});
                
                app.ax.units{ui} = axes(app.UIFigure, 'Position', [ui* 0.1+offset, 0.85, 0.08, 0.13]);
                app.hd.units{ui} = plot(app.ax.units{ui}, NaN, NaN, '-', 'Color', app.setting.colors{ui});
                
                app.ax.ev_raster{ui} = axes(app.UIFigure, 'Position', [ui* 0.1+offset, 0.37, 0.08, 0.13]);
                app.ax.ev_fr{ui} = axes(app.UIFigure, 'Position', [ui* 0.1+offset, 0.21, 0.08, 0.13]);
                app.hd.ev_raster{ui} = errorbar(app.ax.ev_raster{ui}, NaN, NaN, NaN,'.','CapSize',0, 'Color', app.setting.colors{ui});
                app.hd.ev_fr{ui} = plot(app.ax.ev_fr{ui}, NaN, NaN, '-', 'Color', app.setting.colors{ui});
                
                app.ax.dists{ui} = axes(app.UIFigure, 'Position', [ui* 0.1+offset, 0.69, 0.08, 0.13]);
                app.ax.distss{ui} = axes(app.UIFigure, 'Position', [ui* 0.1+offset, 0.53, 0.08, 0.13]);
                hold(app.ax.dists{ui},"on");
                hold(app.ax.distss{ui},"on");
                %                 hold(app.ax.ev_raster{ui},"on");
                %                 hold(app.ax.ev_fr{ui},"on");
                for vi = 1:(app.setting.n_unit + 1)
                    app.hd.dists{ui, vi} = plot(app.ax.dists{ui}, NaN, NaN, '-', 'Color', app.setting.colors{vi});
                    app.hd.distss{ui, vi} = plot(app.ax.distss{ui}, NaN, NaN, '-', 'Color', app.setting.colors{vi});
                end
                hold(app.ax.dists{ui},"off");
                hold(app.ax.distss{ui},"off");
                %                 hold(app.ax.ev_raster{ui},"off");
                %                 hold(app.ax.ev_fr{ui},"off");
                
                %                 set(app.ax.pvals{ui}, 'XLim', [-5,-0.3]);
            end
            hold(app.ax.pca,"off");
            hold(app.ax.temp,"off");
            hold(app.ax.fr,"off");
        end
        function initialize_uis(app)
            app.UnitDropDown.Items = string(0:app.setting.n_unit);
            app.UnitDropDown_2.Items = string(0:app.setting.n_unit);
            app.p_okEditField.Value = 0.05;
            app.p_rejectEditField.Value = 0.01;
            app.OverwriteSwitch.Value = 'Off';
            app.PC1DropDown.Items = string(1:4);
            app.PC2DropDown.Items = string(1:4);
            app.nPCDropDown.Items = string(1:52);
            app.nPCDropDown.Value = "52";
            app.ReducedRangeCheckBox.Value = 1;
            app.t_preEditField.Value = 500;
            app.t_afterEditField.Value = 1000;
        end
        function reset(app)
            app.user.pc_1 = 1;
            app.user.pc_2 = 2;
            app.PC1DropDown.Value = '1';
            app.PC2DropDown.Value = '2';
            xlabel(app.ax.pca, sprintf('PC %d', app.user.pc_1));
            ylabel(app.ax.pca, sprintf('PC %d', app.user.pc_2));
            app.user.unow = 1;
            app.history.units = {};
            app.temp = [];
        end
        function load_file(app)
            app.files.datadir = uigetdir;
            if ~ischar(app.files.datadir)
                app.files.datadir = './sampledata';
            end
            app.files.files = dir(fullfile(app.files.datadir, 'waveforms*.mat'));
            app.files.fid = 1;
            app.load_data;
        end
        function load_data(app)
            tic
            app.reset;
            if (app.files.fid < 1)
                app.files.fid = 1;
            end
            if (app.files.fid > length(app.files.files))
                app.files.fid = length(app.files.files);
            end
            app.files.currentfile = fullfile(app.files.datadir, app.files.files(app.files.fid).name);
            app.FilenameLabel.Text = sprintf('%d/%d: %s', app.files.fid, length(app.files.files), app.files.currentfile);
            app.data = importdata(app.files.currentfile);
            if ~isfield(app.data, 'n_unit')
                app.data.n_unit = length(app.data.units);
                app.data.units = app.data.units';
                app.data.waves = app.data.waves';
                app.data.spikeTimes = app.data.spikeTimes';
            end
            app.data.units(app.data.units == -1) = app.setting.n_unit;
            app.calc_pca;
            ylm = [min(app.data.waves,[],'all'), max(app.data.waves,[],'all')];
            for ui = 1:(app.setting.n_unit + 1)
                ylim(app.ax.units{ui}, ylm);
            end
            app.reject_magnitude;
            app.compute_distAndP;
            app.display_all;
            disp(sprintf('loading file, toc = %.2f', toc));
        end
        function reject_magnitude(app)
            if  app.rejectEditField.Value == 0
                return;
            end
            units = app.data.units;
            id = any(abs(app.data.waves) > app.rejectEditField.Value,2);
            units(id) = -1;
            app.update_unit(units);
        end
        function calc_pca(app)
            x = app.data.waves;
            n = size(x, 2);
            % mean-subtract the data
            xx = bsxfun(@minus, x, mean(x, 1));
            % Get SVD.  Always use the economy version, i.e. 0 option.  v is already
            % the principal component values ordered largest first.
            [u, s, v] = svd(xx, 0);
            % Get x in the component space.
            if n == 1
                s = s(1);
            else
                s = diag(s);
            end
            y = bsxfun(@times, u, s');
            app.feature.pca.score = y;
            app.feature.pca.coeff = v;
        end
        function display_all(app)
            tic
            app.display_pca;
            app.display_units;
            app.display_dists;
            app.display_property;
            app.display_selection;
            app.display_temp;
            app.display_raster;
            app.autosave;
            disp(sprintf('display all, toc = %.2f', toc));
        end
        function tc = xcount(app, tspk1, xbin)
            for xi = 1:length(xbin)-1
                tc(xi) = sum(tspk1 >= xbin(xi) & tspk1 < xbin(xi+1));
            end
        end
        function display_raster(app)
            if isempty(app.tmmk)
                return;
            end
            lb = app.tmmk.lb;
            tm = app.tmmk.tm;
            id = find(app.labelEditField.Value == lb);
            if isempty(id)
                return;
            end
            tm = tm(id) * 1000;
            t1 = tm - app.t_preEditField.Value;
            t2 = tm + app.t_afterEditField.Value;
            for ui = 0:app.setting.n_unit
                idx = ui == app.data.units;
                if any(idx)
                    st = app.data.spikeTimes(idx);
                    x = [];
                    for si = 1:length(st)
                        id = find(st(si) >= t1 & st(si) < t2);
                        if length(id) == 1
                            x(si) = id;
                            st(si) = st(si) - t1(id);
                        else
                            x(si) = NaN;
                            st(si) = NaN;
                        end
                    end
                    dt =ones(length(x),1);
                    set(app.hd.ev_raster{ui+1}, 'XData', st - app.t_preEditField.Value, 'YData', x', 'YPositiveDelta',dt, 'YNegativeDelta', dt);
                    bin = 0:50:(t2-t1);
                    tc = app.xcount(st, bin);
                    set(app.hd.ev_fr{ui+1}, 'XData', bin(2:end)-app.t_preEditField.Value, 'YData', tc);
                    
                else
                    set(app.hd.ev_raster{ui+1}, 'XData', NaN, 'YData', NaN, 'YPositiveDelta',NaN, 'YNegativeDelta', NaN);
                    set(app.hd.ev_fr{ui+1}, 'XData', NaN, 'YData', NaN);
                end
            end
        end
        function display_property(app)
            % firing rate vs time
            st = app.data.spikeTimes;
            mst = max(st);
            bin = linspace(0, mst, 100);
            for ui = 1:app.setting.n_unit
                idx_g = ui == app.data.units;
                if any(idx_g)
                    [ty, tx] = hist(st(idx_g), bin);
                    set(app.hd.fr{ui+1}, 'XData', tx, 'YData', ty);
                else
                    set(app.hd.fr{ui+1}, 'XData', NaN, 'YData',NaN);
                end
            end
            %              xlabel(app.UIAxes_FR_time, 'time')
            %              ylabel(app.UIAxes_FR_time, 'FR')
            %              title(app.UIAxes_FR_time, '')
        end
        function display_pca(app)
            groups = unique(app.data.units);
            n_unit = app.data.n_unit;
            score = app.feature.pca.score;
            xrg = [min(score(:,app.user.pc_1)), max(score(:,app.user.pc_1))];
            yrg = [min(score(:,app.user.pc_2)), max(score(:,app.user.pc_2))];
            xlim(app.ax.pca, xrg);
            ylim(app.ax.pca, yrg);
            %             tic
            for gi = 0:app.setting.n_unit
                idx_g = gi == app.data.units;
                if any(idx_g)
                    set(app.hd.pca{gi+1}, 'XData', score(idx_g,app.user.pc_1), 'YData', score(idx_g,app.user.pc_2));
                else
                    set(app.hd.pca{gi+1}, 'XData', NaN, 'YData', NaN);
                end
            end
            idpoly = find(arrayfun(@(x)contains(class(x), 'Polygon'), app.ax.pca.Children));
            if ~isempty(idpoly)
                delete(app.ax.pca.Children(idpoly));
            end
            %             disp(sprintf('display pca: %.3f', toc));
        end
        function display_temp(app)
            if ~isempty(app.temp)
                idx_g = app.temp.id == 1;
                ty = app.data.waves(idx_g,:);
                tx = repmat(1:size(ty,2), size(ty,1),1);
                ty = horzcat(ty, NaN(size(ty,1),1));
                tx = horzcat(tx, NaN(size(tx,1),1));
                set(app.hd.temp{1}, 'XData', reshape(tx', 1, []), 'YData',reshape(ty', 1, []));
                title(app.ax.temp, sprintf('# = %d (%.2f%%)', sum(idx_g), mean(idx_g)*100));
                idpoly = find(arrayfun(@(x)contains(class(x), 'Polygon'), app.ax.temp.Children));
                if ~isempty(idpoly)
                    delete(app.ax.temp.Children(idpoly));
                end
            else
                set(app.hd.temp{1}, 'XData', NaN, 'YData', NaN);
                title(app.ax.temp, 'temp zone');
            end
        end
        function display_units(app)
            units = app.data.units;
            %             tic
            for i = 0:app.setting.n_unit
                idx_g = units == i;
                if any(idx_g)
                    ty = app.data.waves(idx_g,:);
                    tx = repmat(1:size(ty,2), size(ty,1),1);
                    ty = horzcat(ty, NaN(size(ty,1),1));
                    tx = horzcat(tx, NaN(size(tx,1),1));
                    set(app.hd.units{i+1}, 'XData', reshape(tx', 1, []), 'YData',reshape(ty', 1, []));
                    title(app.ax.units{i+1}, sprintf('# = %d (%.2f%%)', sum(idx_g), mean(idx_g)*100));
                else
                    set(app.hd.units{i+1}, 'XData', NaN, 'YData',NaN);
                    title(app.ax.units{i+1}, '');
                end
            end
            %             disp(sprintf('display units: %.3f', toc));
        end
        function display_dists(app)
            units = app.data.units;
            %             tic
            dst = app.data.distance_to_unit;
            %             pvl = app.data.pval_belong_unit;
            %             pvl = log(pvl)/log(10);
            %             pbins = -5:0.05:-0.3;
            for i = 0:app.setting.n_unit
                idx_g = units == i;
                if any(idx_g)
                    td = dst(:, i+1);
                    %                     tp = pvl(:, i+1);
                    if app.ReducedRangeCheckBox.Value
                        txlm = [0 max(td(idx_g))*2];
                    else
                        txlm = [0 quantile(td, 0.9)];
                    end
                    if txlm(2) <= 0
                        txlm(2) = max(td);
                    end
                    xbins{i+1} = linspace(0, max(td), ceil(max(td)/mean(diff(sort(td)))));
                else
                    txlm = [0 1];
                    xbins{i+1} = [0 1];
                end
                set(app.ax.dists{i+1}, 'XLim', txlm)
            end
            for i = 0:app.setting.n_unit
                idx_g = units == i;
                for j = 1:(app.setting.n_unit+1)
                    if any(idx_g)
                        [tn,tx] = hist(dst(idx_g, j), xbins{j});
                        %                         [tnp] = histc(pvl(idx_g, j), pbins);
                        set(app.hd.dists{j,i+1}, 'XData', tx, 'YData',tn);
                        %                         set(app.hd.pvals{j,i+1}, 'XData', pbins(1:end-1), 'YData',tnp(1:end-1));
                    else
                        set(app.hd.dists{j,i+1}, 'XData', NaN, 'YData',NaN);
                        %                         set(app.hd.pvals{j,i+1}, 'XData', NaN, 'YData',NaN);
                    end
                end
            end
            %             disp(sprintf('display dists: %.3f', toc));
            app.display_dists_scatter;
        end
        function display_dists_scatter(app)
            units = app.data.units;
            %             tic
            dst = app.data.distance_to_unit;
            dr = app.data.distance_ratio;
            
            for j = 1:app.setting.n_unit
                set(app.ax.distss{j+1}, 'XLim', [0 2]);
            end
            for i = 0:app.setting.n_unit
                idx_g = units == i;
                for j = 1:(app.setting.n_unit)
                    if any(idx_g)
                        [tn,tx] = hist(dr(idx_g, j), [0:0.02:10]);
                        set(app.hd.distss{j+1,i+1}, 'XData',tx, 'YData', tn);
                    else
                        set(app.hd.distss{j+1,i+1}, 'XData', NaN, 'YData',NaN);
                    end
                end
            end
        end
        function display_selection(app)
            for i = 0:app.setting.n_unit
                if app.user.unow == i
                    set(app.ax.units{i+1}, 'Color', [255,192,203]/255);
                else
                    set(app.ax.units{i+1}, 'Color', [1 1 1]);
                end
                if ismember(i, [app.user.unow, 0])
                    idpoly = find(arrayfun(@(x)contains(class(x), 'Polygon'), app.ax.units{i+1}.Children));
                    if ~isempty(idpoly)
                        delete(app.ax.units{i+1}.Children(idpoly));
                    end
                end
            end
        end
        function compute_distAndP(app)
            n_unit = app.data.n_unit;
            % add distance computation here
            dic = []; p = [];
            for ui = 0:app.setting.n_unit
                tid = app.data.units == ui;
                if ~any(tid)
                    dic(:, ui+1) = NaN(n_unit,1);
                    p(:, ui+1) = NaN(n_unit,1);
                else
                    idx_npca = 1:str2num(app.nPCDropDown.Value);
                    % average form
                    twv = app.data.waves(tid,idx_npca);
                    if size(twv, 1) > 1
                        twv_av = mean(twv);
                    else
                        twv_av = twv;
                    end
                    invsig = inv(cov(twv));
                    % compute distance
                    for i = 1:n_unit
                        dic(i,ui+1) = (app.data.waves(i,idx_npca) - twv_av)*invsig * (app.data.waves(i,idx_npca) - twv_av)';
                    end
                    dic(dic <0 ) = 0; % the inverse of twv may be inaccurate, dic can be negative...
                    % compute the probability of belonging to a category
                    dis_us = fitdist(dic(tid, ui+1),'Normal');
                    te = cdf(dis_us, dic(:,ui+1));
                    p(:,ui+1) = min([te, 1-te]')';
                end
            end
            for ui = 1:app.setting.n_unit
                te = dic(:, 2:end);
                te(:,ui) = [];
                te = min(te, [], 2);
                ratio(:, ui) = dic(:, ui+1)./te;
            end
            app.data.distance_to_unit = dic;
            app.data.pval_belong_unit = p;
            app.data.distance_ratio = ratio;
            %             app.calc_classification;
        end
        function assign_unit(app, idx, val_unit, varargin)
            units = app.data.units;
            units(idx) = val_unit;
            app.update_unit(units, varargin{:});
        end
        function isupdate = update_unit(app, unit_new, isdispsave)
            isupdate = false;
            if ~exist('unit_new') || isempty(unit_new)
                return;
            end
            if ~exist('isdispsave') || isempty(isdispsave)
                isdispsave = 1;
            end
            if ~isempty(unit_new) && ~all(unit_new == app.data.units)
                app.history.units{end+1} = app.data.units;
                %                 alock = app.getlockedunits;
                %                 idxlock = ismember(app.data.units, alock);
                if strcmp(app.OverwriteSwitch.Value, 'On')
                    %                     app.data.units(~idxlock) = unit_new(~idxlock);
                    isupdate = true;
                    app.data.units = unit_new;
                else
                    tid = app.data.units == 0;
                    %                     app.data.units(tid & ~idxlock) = unit_new(tid & ~idxlock);
                    isupdate = ~all( app.data.units(tid) == unit_new(tid));
                    app.data.units(tid) = unit_new(tid);
                end
                app.compute_distAndP;
                if isdispsave
                    app.display_all;
                end
            end
        end
        function update_selection(app, id_new)
            app.user.unow = id_new;
            app.display_selection;
        end
        function autosave(app)
            if app.setting.autosave == 1
                app.save;
            end
        end
        function calc_classification(app)
            pval = app.data.pval_belong_unit;
            pval = pval(:, 2:app.setting.n_unit);
            units = app.data.units;
            n = app.data.n_unit;
            thres_reject = app.p_rejectEditField.Value;
            preject = arrayfun(@(x) find(pval(x,:) < thres_reject), 1:n, 'UniformOutput', false);
            nreject = cellfun(@(x) length(x), preject)';
            thres_ok = app.p_okEditField.Value;
            pok = arrayfun(@(x) find(pval(x,:) > thres_ok), 1:n, 'UniformOutput', false);
            nok = cellfun(@(x) length(x), pok)';
            ntot = sum(~isnan(pval)')';
            idyes = (nok + nreject == ntot) & (nok == 1);
            uyes = cellfun(@(x)x, pok(idyes));
            units(~idyes) = 0;
            units(idyes) = uyes;
            
            app.data.gp_safe = units;
            app.data.gp_none = nreject == ntot;
        end
        function sort_safe(app)
            isflag = app.update_unit(app.data.gp_safe, 0);
            if isflag
                app.sort_safe;
            else
                disp('sort(safe) done');
                app.display_all;
            end
        end
        function sort_semisafe(app)
            %             units = app.data.units;
            if isempty(app.temp)
                return;
            end
            %             id = find(app.temp.id);
            %             app.temp = [];
            %
            %             dic = app.data.distance_to_unit;
            %             dic = dic(id, 2:app.setting.n_unit);
            %             pval = app.data.pval_belong_unit;
            %             pval = pval(id, 2:app.setting.n_unit);
            %             te = (min(dic')' == dic) & (max(pval')' == pval);
            %             [xx, yy] = find(te);
            %             id = id(xx);
            %             units(id) = yy;
            %             app.update_unit(units);
            tic;
            units = app.data.units;
            dr = app.data.distance_ratio;
            [rid, gid] = find(dr < 1 & app.temp.id);
            gs = unique(gid);
            for i = 1:length(gs)
                units(rid(gs(i) == gid)) = gs(i);
            end
            app.update_unit(units);
            app.display_temp;
            disp(sprintf("sort(ratio) complet, toc = %.2f", toc));
        end
        
        function save(app)
            waveforms = app.data;
            waveforms.units(waveforms.units == app.setting.n_unit) = -1;
            save(app.files.currentfile, 'waveforms');
        end
        
        function P = InterX(app, L1,varargin)
            error(nargchk(2,3,nargin));
            if nargin == 2,
                L2 = L1;    hF = @lt;   %...Avoid the inclusion of common points
            else
                L2 = varargin{1}; hF = @le;
            end
            
            %...Preliminary stuff
            x1  = L1(1,:)';  x2 = L2(1,:);
            y1  = L1(2,:)';  y2 = L2(2,:);
            dx1 = diff(x1); dy1 = diff(y1);
            dx2 = diff(x2); dy2 = diff(y2);
            
            %...Determine 'signed distances'
            S1 = dx1.*y1(1:end-1) - dy1.*x1(1:end-1);
            S2 = dx2.*y2(1:end-1) - dy2.*x2(1:end-1);
            
            C1 = feval(hF,D(bsxfun(@times,dx1,y2)-bsxfun(@times,dy1,x2),S1),0);
            C2 = feval(hF,D((bsxfun(@times,y1,dx2)-bsxfun(@times,x1,dy2))',S2'),0)';
            
            %...Obtain the segments where an intersection is expected
            [i,j] = find(C1 & C2);
            if isempty(i),P = zeros(2,0);return; end;
            
            %...Transpose and prepare for output
            i=i'; dx2=dx2'; dy2=dy2'; S2 = S2';
            L = dy2(j).*dx1(i) - dy1(i).*dx2(j);
            i = i(L~=0); j=j(L~=0); L=L(L~=0);  %...Avoid divisions by 0
            
            %...Solve system of eqs to get the common points
            P = unique([dx2(j).*S1(i) - dx1(i).*S2(j), ...
                dy2(j).*S1(i) - dy1(i).*S2(j)]./[L L],'rows')';
            
            function u = D(x,y)
                u = bsxfun(@minus,x(:,1:end-1),y).*bsxfun(@minus,x(:,2:end),y);
            end
        end
        function p= mydrawpolygon(app, axes)
        1
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.files.datadir = './sampledata';
            app.files.files = dir(fullfile(app.files.datadir, 'waveforms*.mat'));
            app.files.fid = 1;
            app.load_data;
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            vold = app.OverwriteSwitch.Value;
            app.OverwriteSwitch.Value = 'On';
            app.update_unit(zeros(size(app.data.units,  1),1));
            app.OverwriteSwitch.Value = vold;
        end

        % Button pushed function: addpolygonButton
        function addpolygonButtonPushed(app, event)
            unitnow = app.user.unow;
            p = app.mydrawpolygon(app.ax.pca);
            ct = p.Position;
            score = app.feature.pca.score;
            [xv, yv] = poly2cw(ct(:,1), ct(:,2));
            id = inpolygon(score(:,app.user.pc_1), score(:,app.user.pc_2), xv, yv);
            app.assign_unit(id, unitnow);
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            key = event.Key;
            key = str2num(key);
            if length(key) > 0 && key > 0 && key <= app.setting.n_unit
                app.update_selection(key);
            end
        end

        % Button pushed function: clearselectedButton
        function clearselectedButtonPushed(app, event)
            unitnow = app.user.unow;
            vold = app.OverwriteSwitch.Value;
            app.OverwriteSwitch.Value = 'On';
            app.assign_unit(app.data.units == unitnow, 0);
            app.OverwriteSwitch.Value = vold;
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            if length(app.history.units{end}) == length(app.data.units)
                app.data.units = app.history.units{end};
                app.history.units = app.history.units(1:end-1);
                app.compute_distAndP;
                app.display_all;
            end
        end

        % Button pushed function: PickOutButton
        function PickOutButtonPushed(app, event)
            unow = app.user.unow;
            units = app.data.units;
            tidx = find(units == unow);
            if isempty(tidx)
                return;
            end
            p = app.mydrawline(app.ax.units{unow+1}, 'color', 'k', 'LineWidth', 3, 'HandleVisibility', 'off', ...
                'Visible', 'off', 'InteractionsAllowed','none');
            twv = app.data.waves(tidx, :);
            for ti = 1:size(twv,1)
                x(ti) = length(app.InterX([1:size(twv,2);twv(ti,:)], p.Position'));
            end
            units(tidx(x>0)) = 0;
            vold = app.OverwriteSwitch.Value;
            app.OverwriteSwitch.Value = 'On';
            app.update_unit(units);
            app.OverwriteSwitch.Value = vold;
        end

        % Button pushed function: SafeSortButton
        function SafeSortButtonPushed(app, event)
            app.sort_safe;
        end

        % Button pushed function: CombineUnitButton
        function CombineUnitButtonPushed(app, event)
            u1 = str2num(app.UnitDropDown.Value);
            u2 = str2num(app.UnitDropDown_2.Value);
            if (u1 == u2)
                return;
            end
            
            vold = app.OverwriteSwitch.Value;
            app.OverwriteSwitch.Value = 'On';
            app.assign_unit(app.data.units == u2, u1);
            app.OverwriteSwitch.Value = vold;
            
        end

        % Button pushed function: SqueezeButton
        function SqueezeButtonPushed(app, event)
            units = app.data.units;
            us = unique(units);
            for i = 1:length(us)
                if i-1 ~= us(i)
                    units(units == us(i)) = i-1;
                end
            end
            app.update_unit(units);
        end

        % Button pushed function: PushtotempButton
        function PushtotempButtonPushed(app, event)
            %             id = app.data.gp_none;
            %             app.temp.id = ~id & app.data.units == 0;
            app.display_temp;
        end

        % Button pushed function: SemiSafeSortButton
        function SemiSafeSortButtonPushed(app, event)
            app.sort_semisafe;
        end

        % Value changed function: OverwriteSwitch
        function OverwriteSwitchValueChanged(app, event)
            
        end

        % Button pushed function: PicktotempButton
        function PicktotempButtonPushed(app, event)
            units = app.data.units;
            tidx = find(units == 0);
            if isempty(tidx)
                return;
            end
            p = app.mydrawline(app.ax.units{1});
            twv = app.data.waves(tidx, :);
            for ti = 1:size(twv,1)
                x(ti) = length(app.InterX([1:size(twv,2);twv(ti,:)], p.Position'));
            end
            app.temp.id = false(app.data.n_unit, 1);
            app.temp.id(tidx(x>0)) = 1;
            app.display_temp;
            app.display_selection;
        end

        % Button pushed function: PickbackButton
        function PickbackButtonPushed(app, event)
            p = app.mydrawline(app.ax.temp);
            tid = find(app.temp.id);
            twv = app.data.waves(tid, :);
            for ti = 1:size(twv,1)
                x(ti) = length(app.InterX([1:size(twv,2);twv(ti,:)], p.Position'));
            end
            app.temp.id(tid(x>0)) = 0;
            app.display_temp;
        end

        % Button pushed function: CleartempButton
        function CleartempButtonPushed(app, event)
            app.temp = [];
            app.display_temp;
        end

        % Button pushed function: PCAtotempButton
        function PCAtotempButtonPushed(app, event)
            p = app.mydrawpolygon(app.ax.pca);
            ct = p.Position;
            score = app.feature.pca.score;
            [xv, yv] = poly2cw(ct(:,1), ct(:,2));
            id = inpolygon(score(:,app.user.pc_1), score(:,app.user.pc_2), xv, yv);
            app.temp.id = id & app.data.units == 0;
            app.display_temp;
        end

        % Value changed function: PC1DropDown
        function PC1DropDownValueChanged(app, event)
            value = app.PC1DropDown.Value;
            app.user.pc_1  = str2num(value);
        end

        % Value changed function: PC2DropDown
        function PC2DropDownValueChanged(app, event)
            value = app.PC2DropDown.Value;
            app.user.pc_2 = str2num(value);
            app.display_pca;
        end

        % Button pushed function: NextfileButton
        function NextfileButtonPushed(app, event)
            app.files.fid = app.files.fid + 1;
            app.load_data;
        end

        % Button pushed function: PreviousfileButton
        function PreviousfileButtonPushed(app, event)
            app.files.fid = app.files.fid - 1;
            app.load_data;
        end

        % Button pushed function: LoadfolderButton
        function LoadfolderButtonPushed(app, event)
            
            app.load_file;
        end

        % Button pushed function: LoadtimemarksButton
        function LoadtimemarksButtonPushed(app, event)
            if isempty(app.tmmk)
                [te,tp] = uigetfile('*.csv');
                if ~ischar(te)
                    return;
                end
                app.tmmk = readtable(fullfile(tp,te));
                addpath('/Volumes/Wang/Codes/Wang_Matlab/')
                app.tmmk = W.tab_autofieldcombine(app.tmmk);
            end
        end

        % Value changed function: labelEditField
        function labelEditFieldValueChanged(app, event)
            value = app.labelEditField.Value;
            app.display_raster;
        end

        % Value changed function: nPCDropDown
        function nPCDropDownValueChanged(app, event)
            value = app.nPCDropDown.Value;
            app.compute_distAndP;
            app.display_dists;
        end

        % Value changed function: ReducedRangeCheckBox
        function ReducedRangeCheckBoxValueChanged(app, event)
            value = app.ReducedRangeCheckBox.Value;
            app.display_dists;
        end

        % Button pushed function: SortRatioButton
        function SortRatioButtonPushed(app, event)
            tic;
            units = app.data.units;
            pval = app.data.pval_belong_unit;
            pval = pval(:, 2:end);
            dr = app.data.distance_ratio;
            [rid, gid] = find(dr < 0.5 & pval > 0.001);
            gs = unique(gid);
            for i = 1:length(gs)
                units(rid(gs(i) == gid)) = gs(i);
            end
            app.update_unit(units);
            app.temp.id = any(dr < 0.8,2) & app.data.units == 0;
            app.display_temp;
            disp(sprintf("sort(ratio) complet, toc = %.2f", toc));
        end

        % Value changed function: rejectEditField
        function rejectEditFieldValueChanged(app, event)
            value = app.rejectEditField.Value;
            app.reject_magnitude;
        end

        % Value changed function: t_preEditField
        function t_preEditFieldValueChanged(app, event)
            value = app.t_preEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.resetButton = uibutton(app.UIFigure, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'reset';
            app.addpolygonButton = uibutton(app.UIFigure, 'push');
            app.addpolygonButton.ButtonPushedFcn = createCallbackFcn(app, @addpolygonButtonPushed, true);
            app.addpolygonButton.Text = 'add polygon';
            app.clearselectedButton = uibutton(app.UIFigure, 'push');
            app.clearselectedButton.ButtonPushedFcn = createCallbackFcn(app, @clearselectedButtonPushed, true);
            app.clearselectedButton.Text = 'clear selected';
            app.UndoButton = uibutton(app.UIFigure, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Text = 'Undo';
            app.PickOutButton = uibutton(app.UIFigure, 'push');
            app.PickOutButton.ButtonPushedFcn = createCallbackFcn(app, @PickOutButtonPushed, true);
            app.PickOutButton.Text = 'Pick Out';
            app.SafeSortButton = uibutton(app.UIFigure, 'push');
            app.SafeSortButton.ButtonPushedFcn = createCallbackFcn(app, @SafeSortButtonPushed, true);
            app.SafeSortButton.Text = 'Safe Sort';
            app.CombineUnitButton = uibutton(app.UIFigure, 'push');
            app.CombineUnitButton.ButtonPushedFcn = createCallbackFcn(app, @CombineUnitButtonPushed, true);
            app.CombineUnitButton.Text = 'Combine Unit';
            app.UnitDropDownLabel = uilabel(app.UIFigure);
            app.UnitDropDownLabel.Text = 'Unit';
            app.UnitDropDown = uidropdown(app.UIFigure);
            app.UnitDropDown.Items = {};
            app.UnitDropDown.Value = {};
            app.UnitDropDown_2Label = uilabel(app.UIFigure);
            app.UnitDropDown_2Label.Text = 'Unit';
            app.UnitDropDown_2 = uidropdown(app.UIFigure);
            app.UnitDropDown_2.Items = {};
            app.UnitDropDown_2.Value = {};
            app.SqueezeButton = uibutton(app.UIFigure, 'push');
            app.SqueezeButton.ButtonPushedFcn = createCallbackFcn(app, @SqueezeButtonPushed, true);
            app.SqueezeButton.Text = 'Squeeze';
            app.PushtotempButton = uibutton(app.UIFigure, 'push');
            app.PushtotempButton.ButtonPushedFcn = createCallbackFcn(app, @PushtotempButtonPushed, true);
            app.PushtotempButton.Text = 'Push to temp';
            app.p_okEditFieldLabel = uilabel(app.UIFigure);
            app.p_okEditFieldLabel.Text = 'p_ok';
            app.p_okEditField = uieditfield(app.UIFigure, 'numeric');
            app.p_rejectEditFieldLabel = uilabel(app.UIFigure);
            app.p_rejectEditFieldLabel.Text = 'p_reject';
            app.p_rejectEditField = uieditfield(app.UIFigure, 'numeric');
            app.SemiSafeSortButton = uibutton(app.UIFigure, 'push');
            app.SemiSafeSortButton.ButtonPushedFcn = createCallbackFcn(app, @SemiSafeSortButtonPushed, true);
            app.SemiSafeSortButton.Text = 'Semi-Safe Sort';
            app.OverwriteSwitchLabel = uilabel(app.UIFigure);
            app.OverwriteSwitchLabel.Text = 'Overwrite';
            app.OverwriteSwitch = uiswitch(app.UIFigure, 'slider');
            app.OverwriteSwitch.ValueChangedFcn = createCallbackFcn(app, @OverwriteSwitchValueChanged, true);
            app.PicktotempButton = uibutton(app.UIFigure, 'push');
            app.PicktotempButton.ButtonPushedFcn = createCallbackFcn(app, @PicktotempButtonPushed, true);
            app.PicktotempButton.Text = 'Pick to temp';
            app.PickbackButton = uibutton(app.UIFigure, 'push');
            app.PickbackButton.ButtonPushedFcn = createCallbackFcn(app, @PickbackButtonPushed, true);
            app.PickbackButton.Text = 'Pick back';
            app.CleartempButton = uibutton(app.UIFigure, 'push');
            app.CleartempButton.ButtonPushedFcn = createCallbackFcn(app, @CleartempButtonPushed, true);
            app.CleartempButton.Text = 'Clear temp';
            app.PCAtotempButton = uibutton(app.UIFigure, 'push');
            app.PCAtotempButton.ButtonPushedFcn = createCallbackFcn(app, @PCAtotempButtonPushed, true);
            app.PCAtotempButton.Text = 'PCA to temp';
            app.PC1DropDownLabel = uilabel(app.UIFigure);
            app.PC1DropDownLabel.Text = 'PC1';
            app.PC1DropDown = uidropdown(app.UIFigure);
            app.PC1DropDown.ValueChangedFcn = createCallbackFcn(app, @PC1DropDownValueChanged, true);
            app.PC2DropDownLabel = uilabel(app.UIFigure);
            app.PC2DropDownLabel.Text = 'PC2';
            app.PC2DropDown = uidropdown(app.UIFigure);
            app.PC2DropDown.ValueChangedFcn = createCallbackFcn(app, @PC2DropDownValueChanged, true);
            app.FilenameLabel = uilabel(app.UIFigure);
            app.FilenameLabel.Text = 'Filename';
            app.NextfileButton = uibutton(app.UIFigure, 'push');
            app.NextfileButton.ButtonPushedFcn = createCallbackFcn(app, @NextfileButtonPushed, true);
            app.NextfileButton.Text = 'Next file';
            app.PreviousfileButton = uibutton(app.UIFigure, 'push');
            app.PreviousfileButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousfileButtonPushed, true);
            app.PreviousfileButton.Text = 'Previous file';
            app.LoadfolderButton = uibutton(app.UIFigure, 'push');
            app.LoadfolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfolderButtonPushed, true);
            app.LoadfolderButton.Text = 'Load folder';
            app.LoadtimemarksButton = uibutton(app.UIFigure, 'push');
            app.LoadtimemarksButton.ButtonPushedFcn = createCallbackFcn(app, @LoadtimemarksButtonPushed, true);
            app.LoadtimemarksButton.Text = 'Load time marks';
            app.labelEditFieldLabel = uilabel(app.UIFigure);
            app.labelEditFieldLabel.Text = 'label';
            app.labelEditField = uieditfield(app.UIFigure, 'numeric');
            app.labelEditField.ValueChangedFcn = createCallbackFcn(app, @labelEditFieldValueChanged, true);
            app.t_preEditFieldLabel = uilabel(app.UIFigure);
            app.t_preEditFieldLabel.Text = 't_pre';
            app.t_preEditField = uieditfield(app.UIFigure, 'numeric');
            app.t_preEditField.ValueChangedFcn = createCallbackFcn(app, @t_preEditFieldValueChanged, true);
            app.t_afterEditFieldLabel = uilabel(app.UIFigure);
            app.t_afterEditFieldLabel.Text = 't_after';
            app.t_afterEditField = uieditfield(app.UIFigure, 'numeric');
            app.nPCDropDownLabel = uilabel(app.UIFigure);
            app.nPCDropDownLabel.Text = 'nPC';
            app.nPCDropDown = uidropdown(app.UIFigure);
            app.nPCDropDown.ValueChangedFcn = createCallbackFcn(app, @nPCDropDownValueChanged, true);
            app.ReducedRangeCheckBox = uicheckbox(app.UIFigure);
            app.ReducedRangeCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReducedRangeCheckBoxValueChanged, true);
            app.ReducedRangeCheckBox.Text = 'Reduced Range';
            app.SortRatioButton = uibutton(app.UIFigure, 'push');
            app.SortRatioButton.ButtonPushedFcn = createCallbackFcn(app, @SortRatioButtonPushed, true);
            app.SortRatioButton.Text = 'Sort (Ratio)';
            app.rejectEditFieldLabel = uilabel(app.UIFigure);
            app.rejectEditFieldLabel.Text = 'reject';
            app.rejectEditField = uieditfield(app.UIFigure, 'numeric');
            app.rejectEditField.ValueChangedFcn = createCallbackFcn(app, @rejectEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = swsort_v0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end