classdef swsort_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        PC1DropDownLabel        matlab.ui.control.Label
        PC1DropDown             matlab.ui.control.DropDown
        PC2DropDownLabel        matlab.ui.control.Label
        PC2DropDown             matlab.ui.control.DropDown
        FilenameLabel           matlab.ui.control.Label
        rejectEditFieldLabel    matlab.ui.control.Label
        rejectEditField         matlab.ui.control.NumericEditField
        WhattodoDropDownLabel   matlab.ui.control.Label
        WhattodoDropDown        matlab.ui.control.DropDown
        GoButton                matlab.ui.control.Button
        unowDropDownLabel       matlab.ui.control.Label
        unowDropDown            matlab.ui.control.DropDown
        safesortEditFieldLabel  matlab.ui.control.Label
        safesortEditField       matlab.ui.control.NumericEditField
        FinishButton            matlab.ui.control.Button
        ChannelDropDownLabel    matlab.ui.control.Label
        ChannelDropDown         matlab.ui.control.DropDown
        PCEditFieldLabel        matlab.ui.control.Label
        PCEditField             matlab.ui.control.NumericEditField
        u2DropDownLabel         matlab.ui.control.Label
        u2DropDown              matlab.ui.control.DropDown
        ssrEditFieldLabel       matlab.ui.control.Label
        ssrEditField            matlab.ui.control.NumericEditField
    end

    properties (Access = private)
        setting
        ax % axes
        waves
        hd % figure handles for faster plotting
        temp % temp spikes for sorting
        history % for undo purposes
        user % user info
        files % folder/file information
        data
        feature % pca
    end
    methods (Access = private)
        function initialize(app)
            disp('initializing')
            tic
            app.initialize_params; % default parameters
            app.initialize_axes; % set plotting figures
            app.initialize_UI; % initial values of UI objects
            app.reset; % reset (after loading a new dataset)
            toc
        end
        function initialize_params(app)
            app.setting.n_unit = 6; %  number 6 is noise
            app.setting.colors = {'k','r','b','c','m',...
                [0, 0.5, 0],[0.9290, 0.6940, 0.1250], ...
                [0.6350, 0.0780, 0.1840], [0.4940, 0.1840, 0.5560], ...
                [0.83 0.83 0.83]}; % color lists
            app.setting.dotsize = 5;
            app.setting.autosave = 1; % autosave
        end
        function initialize_axes(app)
            app.ax.pca = axes(app.UIFigure, 'Position', [0.03, 0.69, 0.25, 0.3]);
            app.ax.unsorted = axes(app.UIFigure, 'Position', [0.03, 0.37, 0.25, 0.3]);
            app.ax.temp = axes(app.UIFigure, 'Position', [0.03, 0.05, 0.25, 0.3]);
            hold(app.ax.pca,"on");
            hold(app.ax.unsorted,"on");
            hold(app.ax.temp,"on");
            offset = 0.2;
            tlt_units = [arrayfun(@(x)num2str(x),1:app.setting.n_unit,'UniformOutput',false),"noise"];
            for ui = 1:(app.setting.n_unit + 1)
                app.hd.pca{ui} = plot(app.ax.pca, NaN, NaN, '.', 'MarkerSize', app.setting.dotsize, ...
                    'MarkerEdgeColor', app.setting.colors{ui});
                if ui > 1
                    app.hd.unsorted{ui} = plot(app.ax.unsorted, NaN, NaN, '-', 'Color', app.setting.colors{ui}, 'LineWidth', 10);
                    app.hd.unsorted{ui}.Color(4) = 0.2;
                else
                    app.hd.unsorted{ui} = plot(app.ax.unsorted, NaN, NaN, '-', 'Color', app.setting.colors{ui});
                end
                app.hd.temp{ui} = plot(app.ax.temp, NaN, NaN, '-', 'Color', app.setting.colors{ui});
                
                app.ax.units{ui} = axes(app.UIFigure, 'Position', [ui* 0.1+offset, 0.85, 0.08, 0.13]);
                app.hd.units{ui} = plot(app.ax.units{ui}, NaN, NaN, '-', 'Color', app.setting.colors{ui+1});
                title(app.ax.units{ui}, tlt_units(ui));
                app.ax.dists{ui} = axes(app.UIFigure, 'Position', [ui* 0.1+offset, 0.69, 0.08, 0.13]);
                app.ax.frs{ui} = axes(app.UIFigure, 'Position', [ui* 0.1+offset, 0.37, 0.08, 0.13]);
                app.ax.iti{ui} = axes(app.UIFigure, 'Position', [ui* 0.1+offset, 0.21, 0.08, 0.13]);
                app.ax.distratios{ui} = axes(app.UIFigure, 'Position', [ui* 0.1+offset, 0.53, 0.08, 0.13]);
                hold(app.ax.dists{ui},"on");
                hold(app.ax.distratios{ui},"on");
                hold(app.ax.frs{ui},"on");
                hold(app.ax.iti{ui},"on");
                for vi = 1:(app.setting.n_unit + 1)
                    app.hd.dists{ui, vi} = plot(app.ax.dists{ui}, NaN, NaN, '-', 'Color', app.setting.colors{vi});
                    app.hd.distratios{ui, vi} = plot(app.ax.distratios{ui}, NaN, NaN, '-', 'Color', app.setting.colors{vi});
                    app.hd.frs{ui, vi} = plot(app.ax.frs{ui}, NaN, NaN, '-', 'Color', app.setting.colors{vi});
                    app.hd.iti{ui, vi} = plot(app.ax.iti{ui}, NaN, NaN, '-', 'Color', app.setting.colors{vi});
                end
                hold(app.ax.dists{ui},"off");
                hold(app.ax.distratios{ui},"off");
                hold(app.ax.frs{ui},"off");
                hold(app.ax.iti{ui},"off");
            end
            app.hd.pca_ui = plot(app.ax.pca, NaN, NaN, '-', 'Color', [253 238 0]/255, 'LineWidth', 5);
            app.hd.unsorted_ui = plot(app.ax.unsorted, NaN, NaN, '-', 'Color', [253 238 0]/255, 'LineWidth', 5);
            hold(app.ax.pca,"off");
            hold(app.ax.temp,"off");
            hold(app.ax.unsorted,"off");
        end
        function initialize_UI(app)
            app.PC1DropDown.Items = string(1:4);
            app.PC2DropDown.Items = string(1:4);
            app.WhattodoDropDown.Items = {'next','previous', 'loadfolder', 'undo','clearall',...
                'pca_add','line_add', ...
                'sort_safe','sort_temp',...
                'temp_pickout', 'unlabel_pickout', ...
                'set_noise', 'squeeze','combine'};
            app.WhattodoDropDown.Value = 'next';
            app.unowDropDown.Items = [string(1:app.setting.n_unit), "temp"];
            app.u2DropDown.Items = [string(0:app.setting.n_unit)];
            app.PCEditField.Value = 52;
            app.ssrEditField.Value = 1.5;
        end
        function reset(app)
            app.PC1DropDown.Value = '1';
            app.PC2DropDown.Value = '2';
            app.unowDropDown.Value = '1';
            app.u2DropDown.Value = '0';
            app.user.unow = 1; % which one to select
            app.user.isoverwrite = false;
            app.history.units = {};
            app.temp = [];
        end
        function func_combine(app)
            tunit = app.u2DropDown.Value;
            tunit = str2num(tunit);
            units = app.data.units;
            units(units == tunit) = app.user.unow;
            app.user.isoverwrite = true;
            app.update_unit(units);
            app.user.isoverwrite = false;
        end
        function func_loadfolder(app)
            app.files.datadir = uigetdir;
            if ~ischar(app.files.datadir)
                app.files.datadir = './sampledata';
            end
            app.load_files;
        end
        function load_files(app)
            app.files.files = dir(fullfile(app.files.datadir, 'waveforms*.mat'));
            app.files.fid = 1;
            app.ChannelDropDown.Items = string(1:length(app.files.files));
            app.load_data;
        end
        function loadnextfile(app)
            app.files.fid = app.files.fid+1;
            app.load_data;
        end
        function load_data(app)
            disp('loading file');
            tic
            app.reset;
            if (app.files.fid < 1)
                app.files.fid = 1;
            end
            if (app.files.fid > length(app.files.files))
                app.files.fid = length(app.files.files);
            end
            app.files.currentfile = fullfile(app.files.datadir, app.files.files(app.files.fid).name);
            app.FilenameLabel.Text = sprintf('%d/%d: %s', app.files.fid, length(app.files.files), app.files.currentfile);
            app.data = importdata(app.files.currentfile);
            if size(app.data.units, 1) == 1 %
                app.data.swsort_complete = 0;
                app.data.n_unit = length(app.data.units);
                app.data.units = app.data.units';
                app.data.waves = app.data.waves';
                app.data.spikeTimes = app.data.spikeTimes';
            end
            app.waves = app.data.waves;
            app.data.units(app.data.units == -1) = app.setting.n_unit + 1;
            toc
            app.reject_magnitude;
            app.calc_pca;
            app.compute_dists;
            app.display_all;
        end
        function calc_pca(app)
            disp('computing pca')
            tic
            x = app.data.waves;
            n = size(x, 2);
            % mean-subtract the data
            xx = bsxfun(@minus, x, mean(x, 1));
            % Get SVD.  Always use the economy version, i.e. 0 option.  v is already
            % the principal component values ordered largest first.
            [u, s, v] = svd(xx, 0);
            % Get x in the component space.
            if n == 1
                s = s(1);
            else
                s = diag(s);
            end
            y = bsxfun(@times, u, s');
            app.feature.pca.score = y;
            app.feature.pca.coeff = v;
            toc
            app.pca2wave;
        end
        function compute_dists(app)
            disp('computing dist')
            n_unit = app.data.n_unit;
            % add distance computation here
            dic = []; p = [];
            waves = app.feature.pca.score(:, 1:min( size(app.feature.pca.score,1),app.PCEditField.Value));
            for ui = 1:app.setting.n_unit
                tid = app.data.units == ui;
                if ~any(tid) %|| sum(tid) < 1000
                    dic(:, ui) = NaN(n_unit,1);
                    p(:, ui) = NaN(n_unit,1);
                else
                    % average form
                    twv = waves(tid,:);
                    if size(twv, 1) > 1
                        twv_av = mean(twv);
                    else
                        twv_av = twv;
                    end
                    invsig = inv(cov(twv));
                    % compute distance
                    for i = 1:n_unit
                        dic(i,ui) = (waves(i,:) - twv_av)*invsig * (waves(i,:) - twv_av)';
                    end
                    dic(dic <0 ) = 0; % the inverse of twv may be inaccurate, dic can be negative...
                    % compute the probability of belonging to a category
%                     try
%                         dis_us = fitdist(dic(tid, ui),'Normal');
%                         te = cdf(dis_us, dic(:,ui));
%                         p(:,ui) = min([te, 1-te]')';
%                     catch
%                         p(:,ui) = NaN(size(dic,1),1);
%                     end
                    % compute the percentile
%                     p(:, ui) = arrayfun(@(x) mean(dic(tid, ui) > x), dic(:,ui));
                end
            end
            for ui = 1:app.setting.n_unit
                te = dic;
                te(:,ui) = [];
                te = min(te, [], 2);
                ratio(:, ui) = dic(:, ui)./te;
            end
            app.data.dist = dic;
            app.data.pval = app.getpval;
            app.data.dist_ratio = ratio;
        end
        function p = getpval(app)
            dic = app.data.dist;
            tr = app.ssrEditField.Value;
            p = [];
            n_unit = app.data.n_unit;
            for ui = 1:app.setting.n_unit
                tid = app.data.units == ui;
                if ~any(tid) %|| sum(tid) < 1000
                    p(:, ui) = NaN(n_unit,1);
                else
                    p(:, ui) = mean(dic(:,ui) < tr * dic(tid, ui)',2);
                end
            end
        end
        function setunitylim(app)
            units = app.data.units;
            xlm = [0 size(app.waves,2)+1];
            ylm = [min(app.waves(units <= app.setting.n_unit,:),[],'all'), max(app.waves(units <= app.setting.n_unit,:),[],'all')];
            if isempty(ylm)
                ylm = [0 1];
            end
            for ui = 1:(app.setting.n_unit + 1)
                ylim(app.ax.units{ui}, ylm);
                xlim(app.ax.units{ui}, xlm);
            end
            xlim(app.ax.unsorted, xlm)
            ylim(app.ax.unsorted, ylm);
            xlim(app.ax.temp, xlm)
            ylim(app.ax.temp, ylm);
        end
        function reject_magnitude(app)
            disp('rejecting magnitude')
            if  app.rejectEditField.Value > 0
                units = app.data.units;
                id = any(abs(app.data.waves) > app.rejectEditField.Value,2);
                %                 app.data.units(~id & units == app.setting.n_unit + 1) = 0;
                units(id) = app.setting.n_unit + 1;
                app.update_unit(units, 0);
            end
            app.setunitylim;
            app.display_units;
        end
        function isupdate = update_unit(app, unit_new, isdispsave)
            isupdate = false;
            if ~exist('unit_new') || isempty(unit_new)
                return;
            end
            if ~exist('isdispsave') || isempty(isdispsave)
                isdispsave = 1;
            end
            if ~isempty(unit_new) && ~all(unit_new == app.data.units)
                app.history.units{end+1} = app.data.units;
                if ~app.user.isoverwrite
                    tid = app.data.units == 0;
                else
                    tid = true(size(app.data.units,1),1);
                end
                isupdate = ~all( app.data.units(tid) == unit_new(tid));
                app.data.units(tid) = unit_new(tid);
            end
            if isupdate
                app.setunitylim;
                app.compute_dists;
                if isdispsave
                    app.display_all;
                end
                app.autosave;
            end
        end
        
        function display_all(app)
            disp('plotting')
            tic
            app.display_pca;
            app.display_units;
            app.display_dists;
            %             app.display_property;
            app.display_fr;
            app.display_iti;
            app.display_selection;
            app.display_temp;
            %             app.display_raster;
            toc
        end
        function pca2wave(app)
            n = app.PCEditField.Value;
            if size(app.data.waves,1) < n
                app.waves = app.data.waves;
            else
                pca = app.feature.pca;
                app.waves = pca.score(:,1:n) * pca.coeff(:,1:n)';
                app.waves = mean(app.data.waves,1) + app.waves;
            end
            app.compute_dists;
        end
        function display_pca(app)
            groups = unique(app.data.units);
            n_unit = app.data.n_unit;
            score = app.feature.pca.score;
            pc_1 = str2num(app.PC1DropDown.Value);
            pc_2 = str2num(app.PC2DropDown.Value);
            idexclude = app.data.units == (app.setting.n_unit + 1);
            try
                xrg = [min(score(~idexclude,pc_1)), max(score(~idexclude,pc_1))];
                yrg = [min(score(~idexclude,pc_2)), max(score(~idexclude,pc_2))];
                xlim(app.ax.pca, xrg);
                ylim(app.ax.pca, yrg);
            end
            for gi = 0:app.setting.n_unit
                idx_g = gi == app.data.units;
                if any(idx_g)
                    set(app.hd.pca{gi+1}, 'XData', score(idx_g,pc_1), 'YData', score(idx_g,pc_2));
                else
                    set(app.hd.pca{gi+1}, 'XData', NaN, 'YData', NaN);
                end
            end
        end
        function display_temp(app)
            if ~isempty(app.temp)
                app.temp.id = app.temp.id == 1 & app.data.units == 0;
                idx_g = app.temp.id == 1;
                ty = app.waves(idx_g,:);
                tx = repmat(1:size(ty,2), size(ty,1),1);
                ty = horzcat(ty, NaN(size(ty,1),1));
                tx = horzcat(tx, NaN(size(tx,1),1));
                set(app.hd.temp{1}, 'XData', reshape(tx', 1, []), 'YData',reshape(ty', 1, []));
                title(app.ax.temp, sprintf('# = %d (%.2f%%)', sum(idx_g), mean(idx_g)*100));
                %                 idpoly = find(arrayfun(@(x)contains(class(x), 'Polygon'), app.ax.temp.Children));
                %                 if ~isempty(idpoly)
                %                     delete(app.ax.temp.Children(idpoly));
                %                 end
            else
                set(app.hd.temp{1}, 'XData', NaN, 'YData', NaN);
                title(app.ax.temp, 'temp zone');
            end
        end
        function display_units(app, is0)
            if ~exist('is0')
                is0 = 1; % 1 - everything, 0 - unsorted only
            end
            units = app.data.units;
            idx_g = units == 0;
            ty = app.waves(idx_g,:);
            tx = repmat(1:size(ty,2), size(ty,1),1);
            ty = horzcat(ty, NaN(size(ty,1),1));
            tx = horzcat(tx, NaN(size(tx,1),1));
            set(app.hd.unsorted{1}, 'XData', reshape(tx', 1, []), 'YData',reshape(ty', 1, []));
            title(app.ax.unsorted, sprintf('# = %d (%.2f%%)', sum(idx_g), mean(idx_g)*100));
            if is0 == 0
                return;
            end
            for i = 1:app.setting.n_unit + 1
                idx_g = units == i;
                if any(idx_g)
                    ty = app.waves(idx_g,:);
                    tx = repmat(1:size(ty,2), size(ty,1),1);
                    ty = horzcat(ty, NaN(size(ty,1),1));
                    tx = horzcat(tx, NaN(size(tx,1),1));
                    set(app.hd.units{i}, 'XData', reshape(tx', 1, []), 'YData',reshape(ty', 1, []));
                    title(app.ax.units{i}, sprintf('# = %d (%.2f%%)', sum(idx_g), mean(idx_g)*100));
                    if i <= app.setting.n_unit
                        set(app.hd.unsorted{i+1}, 'XData', 1:size(ty,2), 'YData',mean(ty));
                    end
                else
                    set(app.hd.units{i}, 'XData', NaN, 'YData',NaN);
                    title(app.ax.units{i}, '');
                    if i <= app.setting.n_unit
                        set(app.hd.unsorted{i+1}, 'XData', NaN, 'YData',NaN);
                    end
                end
            end
        end
        function display_selection(app)
            for i = 1:app.setting.n_unit
                if app.user.unow == i
                    set(app.ax.units{i}, 'Color', [255,192,203]/255);
                else
                    set(app.ax.units{i}, 'Color', [1 1 1]);
                end
                %                 if ismember(i, [app.user.unow, 0])
                %                     idpoly = find(arrayfun(@(x)contains(class(x), 'Polygon'), app.ax.units{i+1}.Children));
                %                     if ~isempty(idpoly)
                %                         delete(app.ax.units{i+1}.Children(idpoly));
                %                     end
                %                 end
            end
        end
        
        function display_dists(app)
            units = app.data.units;
            dst = app.data.dist;
            dr = app.data.dist_ratio;
            for i = 1:app.setting.n_unit
                idx_g = units == i;
                if any(idx_g)
                    td = dst(:, i);
                    %                     if app.ReducedRangeCheckBox.Value
                    txlm = [0 max(td)];%[0 max(td(idx_g))*2];
                    %                     else
                    %                         txlm = [0 quantile(td, 0.9)];
                    %                     end
                    if txlm(2) <= 0
                        txlm(2) = max(td);
                    end
                    xbins{i} = linspace(0, max(td), ceil(max(td)/mean(diff(sort(td)))));
                else
                    txlm = [0 1];
                    xbins{i} = [0 1];
                end
                set(app.ax.dists{i}, 'XLim', txlm);
                set(app.ax.distratios{i}, 'XLim', [0 1]);
            end
            for i = 0:app.setting.n_unit
                idx_g = units == i;
                for j = 1:(app.setting.n_unit)
                    if any(idx_g)
                        [tn,tx] = hist(dst(idx_g, j), xbins{j});
                        set(app.hd.dists{j,i+1}, 'XData', tx, 'YData',tn);
                        [tn,tx] = hist(dr(idx_g, j), [-1:0.02:10]);
                        set(app.hd.distratios{j,i+1}, 'XData',tx, 'YData', tn);
                    else
                        set(app.hd.dists{j,i+1}, 'XData', NaN, 'YData',NaN);
                        set(app.hd.distratios{j,i+1}, 'XData', NaN, 'YData',NaN);
                    end
                end
            end
        end
        function display_iti(app)
            units = app.data.units;
            tm = app.data.spikeTimes;
            for i = 1:app.setting.n_unit
                set(app.ax.iti{i}, 'XLim', [0 100]);
            end
            xbin = linspace(0, 200, 200);
            for i = 0:app.setting.n_unit
                idx_g = units == i;
                for j = 1:(app.setting.n_unit)
                    if j == i && any(idx_g)
                        [tn, tx] = hist(diff(tm(idx_g)), xbin);
                        set(app.hd.iti{j,i+1}, 'XData',tx, 'YData', tn);
                    else
                        set(app.hd.iti{j,i+1}, 'XData', NaN, 'YData',NaN);
                    end
                end
            end
        end
        function display_fr(app)
            units = app.data.units;
            tm = app.data.spikeTimes;
            for i = 1:app.setting.n_unit
                set(app.ax.frs{i}, 'XLim', [0 max(tm)]);
            end
            xbin = linspace(0, max(tm), 100);
            for i = 0:app.setting.n_unit
                idx_g = units == i;
                for j = 1:(app.setting.n_unit)
                    if j == i && any(idx_g)
                        [tn, tx] = hist(tm(idx_g), xbin);
                        set(app.hd.frs{j,i+1}, 'XData',tx, 'YData', tn);
                    else
                        set(app.hd.frs{j,i+1}, 'XData', NaN, 'YData',NaN);
                    end
                end
            end
        end
        function autosave(app)
            if app.setting.autosave == 1
                app.save;
            end
        end
        function save(app, iscomplete)
            if ~exist('iscomplete')
                iscomplete = 0;
            end
            waveforms = app.data;
            waveforms.sw_complete = iscomplete;
            waveforms.units(waveforms.units == app.setting.n_unit + 1) = -1;
            save(app.files.currentfile, 'waveforms');
        end
        function func_clearall(app)
            app.user.isoverwrite = true;
            app.update_unit(zeros(size(app.data.units,  1),1));
            app.reject_magnitude;
            app.user.isoverwrite = false;
        end
        function p = mydrawpolygon(app, ax)
            disp('get polygon');
            tic
            [x,y] = ginput_ax(ax,5);
            p = [x y];
            toc
        end
        function p = mydrawline(app, ax)
            disp('get polygon');
            tic
            [x,y] = ginput_ax(ax,2);
            p = [x y];
            toc
        end
        function func_pca_add(app)
            ct = app.mydrawpolygon(app.ax.pca);
            disp('updating')
            score = app.feature.pca.score;
            [xv, yv] = poly2cw(ct(:,1), ct(:,2));
            pc_1 = str2num(app.PC1DropDown.Value);
            pc_2 = str2num(app.PC2DropDown.Value);
            
            id = inpolygon(score(:,pc_1), score(:,pc_2), xv, yv);
            if app.unowDropDown.Value == "temp"
                app.temp.id = id;
                app.display_temp;
            else
                unitnow = app.user.unow;
                set(app.hd.pca_ui, 'XData',[ct(:,1); ct(1,1)], 'YData', [ct(:,2);ct(1,2)]);
                app.assign_unit(id, unitnow);
            end
        end
        function func_line_add(app)
            p = app.mydrawline(app.ax.unsorted);
            disp('updating')
            
            tid = find(app.data.units == 0);
            x = false(size(app.data.units,1),1);
            twv = app.waves(tid, :);
            for ti = 1:size(twv,1)
                x(tid(ti)) = length(InterX([1:size(twv,2);twv(ti,:)], p')) > 0;
            end
            if app.unowDropDown.Value == "temp"
                app.temp.id = x;
                app.display_temp;
            else
                unitnow = app.user.unow;
                set(app.hd.unsorted_ui, 'XData',p(:,1), 'YData',p(:,2));
                app.assign_unit(x, unitnow);
            end
        end
        function assign_unit(app, idx, val_unit, varargin)
            units = app.data.units;
            units(idx) = val_unit;
            app.update_unit(units, varargin{:});
        end
        function func_sort_safe(app)
            disp('sort safe');
            tic;
            units = app.data.units;
            pval = app.getpval;
            dr = app.data.dist_ratio;
            if ~all(isnan(dr),'all')
                rthres = app.safesortEditField.Value;
                [rid, gid] = find(dr < rthres & pval > 0.01);
                gs = unique(gid);
                for i = 1:length(gs)
                    units(rid(gs(i) == gid)) = gs(i);
                end
                app.temp.id = any(dr < rthres,2) & app.data.units == 0;
            else
                [rid, gid] = find(pval > 0.01);
                gs = unique(gid);
                for i = 1:length(gs)
                    units(rid(gs(i) == gid)) = gs(i);
                end
                app.temp.id = any(pval > 0.001,2);
            end
            app.update_unit(units);
            app.display_temp;
            toc
        end
        function func_temp_pickout(app)
            disp('temp: pickout');
            tic;
            p = app.mydrawline(app.ax.temp);
            tid = find(app.temp.id);
            twv = app.waves(tid, :);
            for ti = 1:size(twv,1)
                x(ti) = length(InterX([1:size(twv,2);twv(ti,:)], p'));
            end
            app.temp.id(tid(x>0)) = 0;
            app.display_temp;
            toc
        end
        function func_sort_temp(app)
            if isempty(app.temp)
                return;
            end
            disp('sort temp (forced)');
            tic;
            units = app.data.units;
            dr = app.data.dist_ratio;
            if ~all(isnan(dr),'all')
                [rid, gid] = find(dr < 1 & app.temp.id);
                gs = unique(gid);
                for i = 1:length(gs)
                    units(rid(gs(i) == gid)) = gs(i);
                end
            else
                [rid, gid] = find(app.temp.id);
                gs = unique(gid);
                for i = 1:length(gs)
                    units(rid(gs(i) == gid)) = gs(i);
                end
            end
            app.update_unit(units);
            app.display_temp;
            toc
        end
        function func_undo(app)
            if length(app.history.units{end}) == length(app.data.units)
                app.data.units = app.history.units{end};
                app.history.units = app.history.units(1:end-1);
                app.compute_dists;
                app.display_all;
            end
        end
        function func_unlabel_pickout(app)
            unow = app.user.unow;
            units = app.data.units;
            tidx = find(units == unow);
            if isempty(tidx)
                return;
            end
            p = app.mydrawline(app.ax.units{unow});
            twv = app.waves(tidx, :);
            for ti = 1:size(twv,1)
                x(ti) = length(InterX([1:size(twv,2);twv(ti,:)], p'));
            end
            units(tidx(x>0)) = 0;
            app.user.isoverwrite = true;
            app.update_unit(units);
            app.user.isoverwrite = false;
        end
        function func_next(app)
            app.loadnextfile;
        end
        function func_previous(app)
            app.files.fid = app.files.fid - 1;
            app.load_data;
        end
        function func_set_noise(app)
            units = app.data.units;
            units(units == app.user.unow) = app.setting.n_unit + 1;
            app.user.isoverwrite = true;
            app.update_unit(units);
            app.user.isoverwrite = false;
        end
        function func_squeeze(app)
            units = app.data.units;
            us = setdiff(unique(units), [0 app.setting.n_unit+1]);
            for i = 1:length(us)
                if i ~= us(i)
                    units(units == us(i)) = i;
                end
            end
            app.user.isoverwrite = true;
            app.update_unit(units);
            app.user.isoverwrite = false;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.initialize;
            app.files.datadir = './sampledata';
            app.load_files;
        end

        % Value changed function: rejectEditField
        function rejectEditFieldValueChanged(app, event)
            value = app.rejectEditField.Value;
            app.reject_magnitude;
        end

        % Value changed function: PC2DropDown
        function PC2DropDownValueChanged(app, event)
            value = app.PC2DropDown.Value;
            app.display_pca;
        end

        % Value changed function: PC1DropDown
        function PC1DropDownValueChanged(app, event)
            value = app.PC1DropDown.Value;
            app.display_pca;
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            value = app.WhattodoDropDown.Value;
            items = app.WhattodoDropDown.Items;
            funcstr = strcat('app.func_', value);
            eval(funcstr);
        end

        % Value changed function: unowDropDown
        function unowDropDownValueChanged(app, event)
            value = app.unowDropDown.Value;
            if value == "temp"
            else
                app.user.unow = str2num(value);
                app.display_selection;
            end
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            app.save;%(1);
            app.loadnextfile;
        end

        % Value changed function: ChannelDropDown
        function ChannelDropDownValueChanged(app, event)
            value = app.ChannelDropDown.Value;
            app.files.fid = str2num(value);
            app.load_data
        end

        % Value changed function: PCEditField
        function PCEditFieldValueChanged(app, event)
            app.pca2wave;
            app.display_all;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = 'IMG_0656.jpeg';
            app.PC1DropDownLabel = uilabel(app.UIFigure);
            app.PC1DropDownLabel.Text = 'PC1';
            app.PC1DropDown = uidropdown(app.UIFigure);
            app.PC1DropDown.ValueChangedFcn = createCallbackFcn(app, @PC1DropDownValueChanged, true);
            app.PC2DropDownLabel = uilabel(app.UIFigure);
            app.PC2DropDownLabel.Text = 'PC2';
            app.PC2DropDown = uidropdown(app.UIFigure);
            app.PC2DropDown.ValueChangedFcn = createCallbackFcn(app, @PC2DropDownValueChanged, true);
            app.FilenameLabel = uilabel(app.UIFigure);
            app.FilenameLabel.Text = 'Filename';
            app.rejectEditFieldLabel = uilabel(app.UIFigure);
            app.rejectEditFieldLabel.Text = 'reject';
            app.rejectEditField = uieditfield(app.UIFigure, 'numeric');
            app.rejectEditField.ValueChangedFcn = createCallbackFcn(app, @rejectEditFieldValueChanged, true);
            app.WhattodoDropDownLabel = uilabel(app.UIFigure);
            app.WhattodoDropDownLabel.Text = 'What to do:';
            app.WhattodoDropDown = uidropdown(app.UIFigure);
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.unowDropDownLabel = uilabel(app.UIFigure);
            app.unowDropDownLabel.Text = 'unow';
            app.unowDropDown = uidropdown(app.UIFigure);
            app.unowDropDown.ValueChangedFcn = createCallbackFcn(app, @unowDropDownValueChanged, true);
            app.safesortEditFieldLabel = uilabel(app.UIFigure);
            app.safesortEditFieldLabel.Text = 'safesort';
            app.safesortEditField = uieditfield(app.UIFigure, 'numeric');
            app.safesortEditField.Value = 0.8;
            app.FinishButton = uibutton(app.UIFigure, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.ChannelDropDownLabel = uilabel(app.UIFigure);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.UIFigure);
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDownValueChanged, true);
            app.PCEditFieldLabel = uilabel(app.UIFigure);
            app.PCEditFieldLabel.Text = 'PC#';
            app.PCEditField = uieditfield(app.UIFigure, 'numeric');
            app.PCEditField.ValueChangedFcn = createCallbackFcn(app, @PCEditFieldValueChanged, true);
            app.u2DropDownLabel = uilabel(app.UIFigure);
            app.u2DropDownLabel.Text = 'u2';
            app.u2DropDown = uidropdown(app.UIFigure);
            app.ssrEditFieldLabel = uilabel(app.UIFigure);
            app.ssrEditFieldLabel.Text = 'ssr';
            app.ssrEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = swsort_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end